classdef SarConnect < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        OpenHereButton              matlab.ui.control.Button
        AddInPortonParentCheckBox   matlab.ui.control.CheckBox
        AddOutPortonParentCheckBox  matlab.ui.control.CheckBox
        Label_2                     matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        PortnameValidFlagLamp       matlab.ui.control.Lamp
        PortnameValidFlagLampLabel  matlab.ui.control.Label
        addinportCheckBox           matlab.ui.control.CheckBox
        addoutportCheckBox          matlab.ui.control.CheckBox
        ADDPORTButton               matlab.ui.control.Button
        PortNameEditField           matlab.ui.control.EditField
        PortNameEditFieldLabel      matlab.ui.control.Label
        inputlistListBox            matlab.ui.control.ListBox
        inputlistListBoxLabel       matlab.ui.control.Label
        outputlistListBox           matlab.ui.control.ListBox
        outputlistListBoxLabel      matlab.ui.control.Label
        BackButton                  matlab.ui.control.Button
        ExploreDropDown             matlab.ui.control.DropDown
        ExploreDropDownLabel        matlab.ui.control.Label
        ModelPathTextArea           matlab.ui.control.TextArea
        ModelPathTextAreaLabel      matlab.ui.control.Label
    end

    
    properties (Access = public)
        %-------------------------
        ArchModel                %  ArchModel
        CrntComposition = {};       % current level composition
        ChildCompositionNum = 0;
        ChildCompositionCell = {};
        ChildComponentNum = 0;
        ChildComponetCell = {};
        CompPathStr = "";
        
        selectInputComps;
        selectOutputComps;

    end
    
    methods (Access = private)
        
        function updateChildItemsList(app)
            % get child comp information
            [tmpCompsItems,tmpCompsItemsData] = SarGetChildComposItem(app.ChildCompositionNum, app.ChildCompositionCell);
            app.ExploreDropDown.Items = tmpCompsItems;
            app.ExploreDropDown.ItemsData = tmpCompsItemsData;
            [tmpCompnItems,tmpCompnItemsData] = SarGetChildComposItem(app.ChildComponentNum, app.ChildComponetCell);
            
            sumTmpCompItems = [tmpCompsItems tmpCompnItems];
            app.inputlistListBox.Items = sumTmpCompItems;
            app.outputlistListBox.Items = sumTmpCompItems;
            
            sumTmpCompItemsData = [tmpCompsItemsData tmpCompnItemsData];
            
            app.inputlistListBox.ItemsData = sumTmpCompItems; % sumTmpCompItemsData;
            app.outputlistListBox.ItemsData = sumTmpCompItems; % sumTmpCompItemsData;
        end
        
        function exploreToSelectCompsition(app, selectComposition)
            [app.CrntComposition, app.ChildCompositionNum, app.ChildCompositionCell, app.ChildComponentNum, app.ChildComponetCell, app.CompPathStr] = SarDiveIntoSelectComps(selectComposition, app.CompPathStr); 
            app.ModelPathTextArea.Value = app.CompPathStr;
        end
        
        function TF = checkPortnameIfValid(app, changingValue)
            if isempty(changingValue)
                TF = 0;
                app.PortnameValidFlagLamp.Color = [1 0 0];
                return;
            end

            pat = '\w*';
            replaceStr = '';
            tmpStr = regexprep(changingValue, pat, replaceStr);
            TF = isempty(tmpStr);
            if TF
                app.PortnameValidFlagLamp.Color = [0 1 0];
            else
                app.PortnameValidFlagLamp.Color = [1 0 0];
            end
        end
        
        function updateAddPortBtnEnable(app, portNameValid)
            if portNameValid && (app.addinportCheckBox.Value || app.addoutportCheckBox.Value)
                app.ADDPORTButton.Enable = "on";
            else
                app.ADDPORTButton.Enable = "off";
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                app.ArchModel = autosar.arch.loadModel(bdroot);
            catch errMsg
                warning("Open a ArchModel first.");
                % select  file

                % pop out 
                inputfileName = uigetfile('*.slx');
                if(inputfileName == 0)
                    error("User canceled.");
                end

                [~, HeaderFilename, ~] = fileparts(inputfileName);
                open_system(HeaderFilename);
                app.ArchModel = autosar.arch.loadModel(bdroot);
                
            end
        
            updateAddPortBtnEnable(app, checkPortnameIfValid(app,app.PortNameEditField.Value));
            %{
            if checkPortnameIfValid(app,app.PortNameEditField.Value)
                app.ADDPORTButton.Enable = "on";
            else
                app.ADDPORTButton.Enable = "off";
            end
            %}
            exploreToSelectCompsition(app, app.ArchModel); 
            updateChildItemsList(app);

            % display in consoleCompPathStr
            displayCrtLevelInfo(app.CrntComposition, app.ChildCompositionNum, app.ChildCompositionCell, app.ChildComponentNum, app.ChildComponetCell, app.CompPathStr);

        end

        % Value changed function: ExploreDropDown
        function ExploreDropDownValueChanged(app, event)
           
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            if isempty(app.CrntComposition.Parent)
                % disp(" ");
                % warning("~~~~~can't backward any more~~~~~");
            else
                expression = '/(\w*)/(\w*)$';
    
                app.CompPathStr = regexprep(app.CompPathStr,expression,'');
                
                disp(app.CompPathStr);

                exploreToSelectCompsition(app, app.CrntComposition.Parent);
                updateChildItemsList(app);

            end

            displayCrtLevelInfo(app.CrntComposition, app.ChildCompositionNum, app.ChildCompositionCell, app.ChildComponentNum, app.ChildComponetCell, app.CompPathStr);

        end

        % Clicked callback: ExploreDropDown
        function ExploreDropDownClicked(app, event)
            num = event.InteractionInformation.Item;
            disp(num);
            if ~isempty(num)
                % disp('..');
                if app.ChildCompositionNum == 0
                    % warning("!!  current composition has no child  !!");
                else
                    exploreToSelectCompsition(app, app.ChildCompositionCell(num));
                    updateChildItemsList(app);
                end

                displayCrtLevelInfo(app.CrntComposition, app.ChildCompositionNum, app.ChildCompositionCell, app.ChildComponentNum, app.ChildComponetCell, app.CompPathStr);

            end
           
        end

        % Button pushed function: ADDPORTButton
        function ADDPORTButtonPushed(app, event)
            disp('---------------------------------------');

            if isempty(app.PortNameEditField.Value)
                warning("please input valid port name.");
            else
                if app.outputlistListBox.Enable
                   
                    
                    % disp('selected output (list box value):');
                    % disp(app.inputlistListBox.Value);

                    app.selectOutputComps = SarGetSelectComps(app.outputlistListBox.Value, app.ChildCompositionNum, app.ChildCompositionCell, app.ChildComponentNum, app.ChildComponetCell);
                    % disp('selected output (comps value):');
                    % disp(app.selectOutputComps);
                    
                    if app.AddOutPortonParentCheckBox.Value
                        disp('Add output on Parent compostion');
                        app.selectOutputComps = [app.CrntComposition, app.selectOutputComps];
                    end
                    
                    SarAddPort(app.selectOutputComps,'Sender', app.PortNameEditField.Value);
                end

                disp('----------------------');

                if app.inputlistListBox.Enable
                    % disp('selected input (list box value):');
                    % disp(app.inputlistListBox.Value);

                    app.selectInputComps = SarGetSelectComps(app.inputlistListBox.Value, app.ChildCompositionNum, app.ChildCompositionCell, app.ChildComponentNum, app.ChildComponetCell);
                    % disp('selected input (comps value):');
                    % disp(app.selectInputComps);
                    % disp(length(app.selectInputComps));

                    if app.AddInPortonParentCheckBox.Value
                        disp('Add input on Parent compostion');
                        app.selectInputComps = [app.CrntComposition, app.selectInputComps];
                    end


                    SarAddPort(app.selectInputComps,'Receiver', app.PortNameEditField.Value);
           
                end
                disp('----------------------');
            end
            
            % disp('----- selected port -------------------');
            % disp(app.inputlistListBox.Value{1});
        end

        % Value changed function: addoutportCheckBox
        function addoutportCheckBoxValueChanged(app, event)
            value = app.addoutportCheckBox.Value;
            app.outputlistListBox.Enable = value;
            if ~value
                app.AddOutPortonParentCheckBox.Value = false;

                % recover add input checkbox enable
                app.addinportCheckBox.Enable = true;
            end

            app.AddOutPortonParentCheckBox.Enable = value;
            updateAddPortBtnEnable(app, checkPortnameIfValid(app,app.PortNameEditField.Value));
        end

        % Value changed function: addinportCheckBox
        function addinportCheckBoxValueChanged(app, event)
            value = app.addinportCheckBox.Value;
            app.inputlistListBox.Enable = value;

            if ~value
                app.AddInPortonParentCheckBox.Value = false;
                % recover add output checkbox enable
                app.addoutportCheckBox.Enable = true;
            end

            app.AddInPortonParentCheckBox.Enable = value;
            updateAddPortBtnEnable(app, checkPortnameIfValid(app,app.PortNameEditField.Value));
        end

        % Value changing function: PortNameEditField
        function PortNameEditFieldValueChanging(app, event)
            changingValue = event.Value;
            updateAddPortBtnEnable(app, checkPortnameIfValid(app,changingValue));
            %{
            if checkPortnameIfValid(app,changingValue) && (app.addinportCheckBox.Value || app.addoutportCheckBox.Value)
                app.ADDPORTButton.Enable = "on";
            else
                app.ADDPORTButton.Enable = "off";
            end
            %}
        end

        % Value changed function: AddOutPortonParentCheckBox
        function AddOutPortonParentCheckBoxValueChanged(app, event)
            value = app.AddOutPortonParentCheckBox.Value;
            if value
                app.addinportCheckBox.Value = false;
                app.AddInPortonParentCheckBox.Enable = false;
                app.inputlistListBox.Enable = false;
            end
            app.addinportCheckBox.Enable = ~value;
        end

        % Value changed function: AddInPortonParentCheckBox
        function AddInPortonParentCheckBoxValueChanged(app, event)
            value = app.AddInPortonParentCheckBox.Value;
            if value
                app.addoutportCheckBox.Value = false;
                app.AddOutPortonParentCheckBox.Enable = false;
                app.outputlistListBox.Enable = false;
            end
            app.addoutportCheckBox.Enable = ~value;

            
        end

        % Button pushed function: OpenHereButton
        function OpenHereButtonPushed(app, event)
            % open path
            tmpModelPath = app.CompPathStr{1};
            % disp(tmpModelPath);
            tmpModelPath = tmpModelPath(2:end);
            % disp(tmpModelPath);
            open_system(tmpModelPath);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ModelPathTextAreaLabel = uilabel(app.UIFigure);
            app.ModelPathTextAreaLabel.Text = 'Model Path';
            app.ModelPathTextArea = uitextarea(app.UIFigure);
            app.ModelPathTextArea.Editable = 'off';
            app.ExploreDropDownLabel = uilabel(app.UIFigure);
            app.ExploreDropDownLabel.Text = 'Explore';
            app.ExploreDropDown = uidropdown(app.UIFigure);
            app.ExploreDropDown.Items = {'Current', 'Option 1', 'Option 2', 'Option 3'};
            app.ExploreDropDown.ItemsData = [0 1 2 3];
            app.ExploreDropDown.ValueChangedFcn = createCallbackFcn(app, @ExploreDropDownValueChanged, true);
            app.ExploreDropDown.ClickedFcn = createCallbackFcn(app, @ExploreDropDownClicked, true);
            app.ExploreDropDown.Value = 0;
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.outputlistListBoxLabel = uilabel(app.UIFigure);
            app.outputlistListBoxLabel.Text = 'output list';
            app.outputlistListBox = uilistbox(app.UIFigure);
            app.inputlistListBoxLabel = uilabel(app.UIFigure);
            app.inputlistListBoxLabel.Text = 'input list';
            app.inputlistListBox = uilistbox(app.UIFigure);
            app.inputlistListBox.Multiselect = 'on';
            app.inputlistListBox.Value = {'Item 1'};
            app.PortNameEditFieldLabel = uilabel(app.UIFigure);
            app.PortNameEditFieldLabel.Text = 'Port Name';
            app.PortNameEditField = uieditfield(app.UIFigure, 'text');
            app.PortNameEditField.ValueChangingFcn = createCallbackFcn(app, @PortNameEditFieldValueChanging, true);
            app.ADDPORTButton = uibutton(app.UIFigure, 'push');
            app.ADDPORTButton.ButtonPushedFcn = createCallbackFcn(app, @ADDPORTButtonPushed, true);
            app.ADDPORTButton.Enable = 'off';
            app.ADDPORTButton.Text = 'ADD PORT';
            app.addoutportCheckBox = uicheckbox(app.UIFigure);
            app.addoutportCheckBox.ValueChangedFcn = createCallbackFcn(app, @addoutportCheckBoxValueChanged, true);
            app.addoutportCheckBox.Text = 'add outport';
            app.addoutportCheckBox.Value = true;
            app.addinportCheckBox = uicheckbox(app.UIFigure);
            app.addinportCheckBox.ValueChangedFcn = createCallbackFcn(app, @addinportCheckBoxValueChanged, true);
            app.addinportCheckBox.Text = 'add inport';
            app.addinportCheckBox.Value = true;
            app.PortnameValidFlagLampLabel = uilabel(app.UIFigure);
            app.PortnameValidFlagLampLabel.Visible = 'off';
            app.PortnameValidFlagLampLabel.Text = 'PortnameValidFlag';
            app.PortnameValidFlagLamp = uilamp(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Interpreter = 'latex';
            app.Label.Text = '--->';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Interpreter = 'latex';
            app.Label_2.Text = '--->';
            app.AddOutPortonParentCheckBox = uicheckbox(app.UIFigure);
            app.AddOutPortonParentCheckBox.ValueChangedFcn = createCallbackFcn(app, @AddOutPortonParentCheckBoxValueChanged, true);
            app.AddOutPortonParentCheckBox.Text = 'Add OutPort on Parent';
            app.AddInPortonParentCheckBox = uicheckbox(app.UIFigure);
            app.AddInPortonParentCheckBox.ValueChangedFcn = createCallbackFcn(app, @AddInPortonParentCheckBoxValueChanged, true);
            app.AddInPortonParentCheckBox.Text = 'Add InPort on Parent';
            app.OpenHereButton = uibutton(app.UIFigure, 'push');
            app.OpenHereButton.ButtonPushedFcn = createCallbackFcn(app, @OpenHereButtonPushed, true);
            app.OpenHereButton.Text = {'Open'; 'Here'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SarConnect

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end